classdef latex_gloveapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LatexGlovesLabel             matlab.ui.control.Label
        SelectDropDown               matlab.ui.control.DropDown
        SelectDropDownLabel          matlab.ui.control.Label
        UploadImageButton            matlab.ui.control.Button
        ApplyDefectsButton           matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.EditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.EditField
        DefectsDetectedListBox       matlab.ui.control.ListBox
        DefectsDetectedListBoxLabel  matlab.ui.control.Label
        ShowMasksButton              matlab.ui.control.Button
        BackButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyDefectsButton
        function ApplyDefectsButtonPushed(app, event)
            switch app.SelectDropDown.Value
                case 'Stain'
                    % Load the input image
                    inputImage = imread('stains.png');
                    
                    % Convert the input image to grayscale
                    grayImage = rgb2gray(inputImage);
                    
                    % Apply noise reduction using median filtering
                    filteredImage = medfilt2(grayImage);
                    
                    % Enhance contrast using histogram equalization
                    enhancedImage = histeq(filteredImage);
                    
                    % Thresholding to segment the glove
                    thresholdValue = graythresh(enhancedImage);
                    binaryImage = imbinarize(enhancedImage, thresholdValue);
                    
                    % Perform morphological operations
                    binaryImage = bwareaopen(binaryImage, 100);
                    binaryImage = imfill(binaryImage, 'holes');
                    binaryImage = bwareafilt(binaryImage, [500, Inf]); % Adjust this minimum area threshold
                    
                    % Identify areas with significant intensity difference from background
                    stainMask = enhancedImage > 0.7; % Adjust this intensity threshold
                    
                    % Combine stain mask with binary image
                    stainBW = binaryImage & stainMask;
                    
                    % Remove small noise regions
                    stainBW = bwareafilt(stainBW, [100, Inf]);
                    
                    % Label stains
                    [stainLabels, numStains] = bwlabel(stainBW);
                    
                    % Display the results
                    imshow(inputImage, 'Parent', app.UIAxes);
                    hold on;
                    if numStains > 0
                        for i = 1:numStains
                            [row, col] = find(stainLabels == i);
                            centerX = round(mean(col));
                            centerY = round(mean(row));
                            text(centerX, centerY, 'Stain', 'FontSize', 12, 'Color', 'blue', 'BackgroundColor', 'white', 'EdgeColor', 'none');
                        end
                    end
                    hold off;
                    
                    app.EditField_2.Value = num2str(numStains);


                case 'Tear'
                    % Load the input image
                    inputImage = imread('tear.png');
                    
                    % Convert the input image to grayscale
                    grayImage = rgb2gray(inputImage);
                    
                    % Apply noise reduction using median filtering
                    filteredImage = medfilt2(grayImage);
                    
                    % Enhance contrast using histogram equalization
                    enhancedImage = histeq(filteredImage);
                    
                    % Thresholding to segment the glove
                    thresholdValue = graythresh(enhancedImage);
                    binaryImage = imbinarize(enhancedImage, thresholdValue);
                    
                    % Perform morphological operations
                    binaryImage = bwareaopen(binaryImage, 100);
                    binaryImage = imfill(binaryImage, 'holes');
                    binaryImage = bwareafilt(binaryImage, [1000, Inf]);
                    
                    % Detect tear
                    tearBW = edge(binaryImage, 'Canny');
                    tearBW = imclose(tearBW, strel('line', 10, 90));
                    [tearLabels, numTears] = bwlabel(tearBW);
                    if numTears > 0
                        for i = 1:numTears
                            [row, col] = find(tearLabels == i);
                            centerX = round(mean(col));
                            centerY = round(mean(row));
                            inputImage = insertText(inputImage, [centerX, centerY], 'Tear', 'FontSize', 12, 'TextColor', 'red', 'BoxColor', 'white', 'BoxOpacity', 0.8);
                        end
                        tearCount = numTears;
                    else
                        tearCount = 0;
                    end
                    
                    % Display the results
                    imshow(inputImage, 'Parent', app.UIAxes);
                    
                    app.EditField_4.Value = num2str(tearCount);


                case 'Missing Finger'
                    % Load the input image
                    inputImage = imread('missingfinger.png');
                    
                    % Convert the input image to grayscale
                    grayImage = rgb2gray(inputImage);
                    
                    % Apply noise reduction using median filtering
                    filteredImage = medfilt2(grayImage);
                    
                    % Enhance contrast using histogram equalization
                    enhancedImage = histeq(filteredImage);
                    
                    % Thresholding to segment the glove
                    thresholdValue = graythresh(enhancedImage);
                    binaryImage = imbinarize(enhancedImage, thresholdValue);
                    
                    % Perform morphological operations
                    binaryImage = bwareaopen(binaryImage, 300);
                    binaryImage = imfill(binaryImage, 'holes');
                    
                    % Label connected components
                    cc = bwconncomp(binaryImage);
                    numPixels = cellfun(@numel, cc.PixelIdxList);
                    [~, idx] = max(numPixels); % Find the largest connected component
                    
                    % Remove the largest component (which represents the glove)
                    binaryImage(cc.PixelIdxList{idx}) = 0;
                    
                    % Label remaining connected components
                    cc = bwconncomp(binaryImage);
                    numMissingFingers = 0;
                    missingFingerCount = 0; % Variable to keep count of missing fingers
                    for i = 1:cc.NumObjects
                        [rows, cols] = ind2sub(size(binaryImage), cc.PixelIdxList{i});
                        width = max(cols) - min(cols);
                        height = max(rows) - min(rows);
                        aspectRatio = width / height;
                        if aspectRatio > 0.8 
                            numMissingFingers = numMissingFingers + 1;
                            missingFingerCount = missingFingerCount + 1; % Increment count of missing fingers
                            % Get bounding box coordinates
                            x1 = max(min(cols) - 10, 1); 
                            y1 = max(min(rows) - 10, 1); 
                            x2 = min(max(cols) + 10, size(inputImage, 2)); 
                            y2 = min(max(rows) + 10, size(inputImage, 1)); 
                            % Draw bounding box
                            inputImage = insertShape(inputImage, 'Rectangle', [x1, y1, x2-x1, y2-y1], 'LineWidth', 2, 'Color', 'green');
                        end
                    end
                    
                    % Display the results
                    imshow(inputImage, 'Parent', app.UIAxes);
                    
                    app.EditField_6.Value = num2str(missingFingerCount);
                    % Variable assigned for keeping count of missing fingers
                    disp(['Number of missing fingers detected: ', num2str(missingFingerCount)]);


            end
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            global img;
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image files'}, 'Select an image');
            % Load the selected image
            img = imread(fullfile(path, file));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.Text = 'Back';
            app.ShowMasksButton = uibutton(app.UIFigure, 'push');
            app.ShowMasksButton.Text = 'Show Masks';
            app.DefectsDetectedListBoxLabel = uilabel(app.UIFigure);
            app.DefectsDetectedListBoxLabel.Text = 'Defects Detected';
            app.DefectsDetectedListBox = uilistbox(app.UIFigure);
            app.DefectsDetectedListBox.Items = {};
            app.DefectsDetectedListBox.Value = {};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Stain';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'Missing finger';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Value = 'Tear';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ApplyDefectsButton = uibutton(app.UIFigure, 'push');
            app.ApplyDefectsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyDefectsButtonPushed, true);
            app.ApplyDefectsButton.Text = 'Apply Defects';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.SelectDropDownLabel = uilabel(app.UIFigure);
            app.SelectDropDownLabel.Text = 'Select ';
            app.SelectDropDown = uidropdown(app.UIFigure);
            app.SelectDropDown.Items = {'Stain', 'Tear', 'Missing Finger'};
            app.SelectDropDown.Value = 'Stain';
            app.LatexGlovesLabel = uilabel(app.UIFigure);
            app.LatexGlovesLabel.Text = 'Latex Gloves';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = latex_gloveapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end