classdef siliconeGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        text2_2      matlab.ui.control.Label
        popupmenu1   matlab.ui.control.DropDown
        pushbutton6  matlab.ui.control.Button
        back         matlab.ui.control.Button
        uipanel2     matlab.ui.container.Panel
        text8        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        Hole         matlab.ui.control.Label
        pushbutton3  matlab.ui.control.Button
        text2        matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        axes1        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit3_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit4_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function siliconeGUI_OpeningFcn(app, varargin)
            % --- Executes just before siliconeGUI is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to siliconeGUI (see VARARGIN)
            
            % Choose default command line output for siliconeGUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: back
        function back_Callback(app, event)
            % --- Executes on button press in back.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to back (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Close the first GUI
                close();
            
                % Create and display the second GUI
                mainMenu_App;
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Clearing all previous image data and ressting the defect counters
             set(handles.text6,'string',  "0");
             set(handles.text7,'string',  "0");
              set(handles.text8,'string',  "0");
            
            
            [File_Name, Path_Name] = uigetfile({'Glove_Defect_Detection_System\silicone\dataset\*.*'},'Select image');
            fullname = fullfile(Path_Name,File_Name);
            im = imread(fullname);
            axes(handles.axes1);
            imshow(im);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % --- Executes on button press in pushbutton3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            input = getimage(handles.axes1);
            
            % Get the image data from the object
            % im = get(input, 'CData');
            
            
            switch get(handles.popupmenu1, 'value')
                            case 1
                                silicone_dirty_stain_detection(input);
                                 load('variables');
                                  set(handles.text6,'string', num2str(numStain));
                            case 2
                                silicone_missing_finger_detection(input);
                                load('variables');
                                set(handles.text7,'string',  num2str(numFingers));
                            case 3
                                silicone_mould_detection(input);
                                 load('variables');
                                set(handles.text8,'string', num2str(numStain));
            
            end
        end

        % Button pushed function: pushbutton6
        function pushbutton6_Callback(app, event)
            % --- Executes on button press in pushbutton6.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            switch get(handles.popupmenu1, 'value')
                            case 1
                                load('variables');
                                figure, imshow(stainMask);
                            case 2
                                load('variables');
                                figure, imshow(finger);
                            case 3
                                load('variables');
                                figure, imshow(mouldMask);
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'siliconeGUI';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Control Panel';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Upload Image';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Silicone Gloves';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Apply Defects';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'Defects Detected:';
            app.uipanel2.Tag = 'uipanel2';
            app.Hole = uilabel(app.uipanel2);
            app.Hole.Tag = 'Hole';
            app.Hole.Text = 'Stain:';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Fingers:';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Mould:';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = '0';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = '0';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = '0';
            app.back = uibutton(app.uipanel1, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @back_Callback, true);
            app.back.Tag = 'back';
            app.back.Text = 'Back';
            app.pushbutton6 = uibutton(app.uipanel1, 'push');
            app.pushbutton6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton6_Callback, true);
            app.pushbutton6.Tag = 'pushbutton6';
            app.pushbutton6.Text = 'Show Masks';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'Stain', 'Fingers', 'Mould'};
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Stain';
            app.text2_2 = uilabel(app.uipanel1);
            app.text2_2.Tag = 'text2';
            app.text2_2.Text = ' Select:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = siliconeGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)siliconeGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end